classdef record < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StartButton  matlab.ui.control.Button
        StopButton   matlab.ui.control.Button
        ECHOLabel    matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
        Label        matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
              global recObj
               recObj = audiorecorder(44100,8, 1) ; %create object
                record(recObj); %start Recording
                app.Label.Text = 'Recording';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
             global recObj
                stop(recObj) % Stop
                app.Label.Text = 'Stop Recording';
                 y=getaudiodata(recObj);
                plot(app.UIAxes,y)
               audiowrite('filename.wav', y,44100);
               
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ECHOLabel = uilabel(app.UIFigure);
            app.ECHOLabel.Text = 'ECHO';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = record

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end